classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DangerZonePanel                matlab.ui.container.Panel
        Label_5                        matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        ExportDataPanel                matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        VelocityandAccelerationButton_2  matlab.ui.control.Button
        VelocityButton_2               matlab.ui.control.Button
        AccelerationButton_2           matlab.ui.control.Button
        VelocityandAccelerationButton  matlab.ui.control.Button
        AccelerationButton             matlab.ui.control.Button
        VelocityButton                 matlab.ui.control.Button
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        ConfigurationTrainTrackandBridgePanel  matlab.ui.container.Panel
        BoundaryConditionsButtonGroup  matlab.ui.container.ButtonGroup
        FixedButton                    matlab.ui.control.RadioButton
        SimplySupportedButton          matlab.ui.control.RadioButton
        BallastButtonGroup             matlab.ui.container.ButtonGroup
        NoBallastonTrackButton         matlab.ui.control.RadioButton
        WithBallastonTrackButton       matlab.ui.control.RadioButton
        ModelTrainPropertiesButtonGroup  matlab.ui.container.ButtonGroup
        Shinkansen                     matlab.ui.control.RadioButton
        PoineerR                       matlab.ui.control.RadioButton
        PoineerM                       matlab.ui.control.RadioButton
        Chinese                        matlab.ui.control.RadioButton
        Eurostar                       matlab.ui.control.RadioButton
        AVE                            matlab.ui.control.RadioButton
        Manchester                     matlab.ui.control.RadioButton
        Label                          matlab.ui.control.Label
        VelocitykmphEditFieldLabel     matlab.ui.control.Label
        VelocitykmphEditField          matlab.ui.control.NumericEditField
        NumberofCoachesExcludingLocomotivesSpinnerLabel  matlab.ui.control.Label
        CoachNum                       matlab.ui.control.Spinner
        ConsoleTextArea                matlab.ui.control.TextArea
        ConsoleTextAreaLabel           matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

properties (Access = public)
    GlobalAcceleration;
    GlobalSpeed;
    GlobalTime;
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
title(app.UIAxes,"Track Acceleration")
xlabel(app.UIAxes,"Time (s)")
ylabel(app.UIAxes,"Acceleration (m/s^2)")
title(app.UIAxes2,"Track Velocity")
xlabel(app.UIAxes2,"Time (s)")
ylabel(app.UIAxes2,"Velocity (m/s)")


% Main script
app.DangerZonePanel.Enable = 'off';
app.ExportDataPanel.Enable = 'off';
app.ConfigurationTrainTrackandBridgePanel.Enable = 'off';
app.RunButton.Enable = 'off';
cla(app.UIAxes);
cla(app.UIAxes2);
VelocitykmphEditFieldValueChanged(app,[]);
clc;

% --------------------------- Definitions ---------------------------------


% ---- Train ----
% Definitions for Train

% Velocity
Train.vel = (app.VelocitykmphEditField.Value)/3.6;          % Train velocity [m/s]

% -- Mechanical properties --

% File loading path
Train.Load.path = '';

% Defnintion of successive vehicles
trmodel = app.ModelTrainPropertiesButtonGroup.SelectedObject;
veh_num = 0;
if trmodel == app.Manchester
    veh_num = 1; run([Train.Load.path,'TrainProp_Manchester_BenchMark']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+1; run([Train.Load.path,'TrainProp_Manchester_BenchMark']);
    end
elseif trmodel == app.AVE
    veh_num = 1; run([Train.Load.path,'TrainProp_AVE_S103_ICE3_Velaro_E_C1']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+1; 
        randomCoach = randi(2);
        if randomCoach == 1
            run([Train.Load.path,'TrainProp_AVE_S103_ICE3_Velaro_E_C2367']);
        else
            run([Train.Load.path,'TrainProp_AVE_S103_ICE3_Velaro_E_C45']);
        end
    end
    veh_num = app.CoachNum.Value+1; run([Train.Load.path,'TrainProp_AVE_S103_ICE3_Velaro_E_C8']);
elseif trmodel == app.Eurostar
    veh_num = 1; run([Train.Load.path,'TrainProp_Eurostar_Locomotive']);
    veh_num = 2; run([Train.Load.path,'TrainProp_Eurostar_EC1']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+2; run([Train.Load.path,'TrainProp_Eurostar_EC2_EC8']);
    end
    veh_num = app.CoachNum.Value+3; run([Train.Load.path,'TrainProp_Eurostar_EC9']);
    veh_num = app.CoachNum.Value+4; run([Train.Load.path,'TrainProp_Eurostar_Locomotive']);
elseif trmodel == app.Chinese
    veh_num = 1; run([Train.Load.path,'TrainProp_ChineseStarPowerCar']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+1; run([Train.Load.path,'TrainProp_DoubleDeckPassengerCoach']);
    end
elseif trmodel == app.PoineerM
    veh_num = 1; run([Train.Load.path,'TrainProp_Pioneer_M_vehicle']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+1; run([Train.Load.path,'TrainProp_Pioneer_M_vehicle']);
    end
elseif trmodel == app.PoineerR
    veh_num = 1; run([Train.Load.path,'TrainProp_Pioneer_R_vehicle']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+1; run([Train.Load.path,'TrainProp_Pioneer_R_vehicle']);
    end
elseif trmodel == app.Shinkansen
    veh_num = 1; run([Train.Load.path,'TrainProp_ShinkansenS300']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+1; run([Train.Load.path,'TrainProp_ShinkansenS300']);
    end    
end


% Copy of Train.vel
Train.Veh(1).vel = Train.vel;

clear veh_num

% ---- End of script ----


% ---- Track ----
% Definition of Track

% File loading path
Track.Load.path = '';

% Loading predefined list of track properties

ballastP = app.BallastButtonGroup.SelectedObject;
if ballastP == app.NoBallastonTrackButton
    run([Track.Load.path,'TrackProp_Zhai_et_al_NoBallastOnBridge']);
else
    run([Track.Load.path,'TrackProp_Zhai_et_al_WithBallastOnBridge']);
end
% -- End of script --


% ---- Bridge ----
% Bridge model definition

% Not a function.
% Defines the content of the variable Beam.

% A 50m bridge. Properties taken from:
% He Xia, Nan Zhang, Guido De Roeck, Dynamic analysis of high speed railway 
%   bridge under articulated trains, Computers & Structures, Volume 81, 
%   Issues 26–27, 2003, Pages 2467-2478, ISSN 0045-7949,
%   https://doi.org/10.1016/S0045-7949(03)00309-2.
Beam.Prop.L = 50;       % Span [m]
Beam.Prop.E = 35e9;     % Modulus of elasticity [N/m^2]
Beam.Prop.I = 51.3;     % Second moment of area [m4]
Beam.Damping.per = 1;   % Damping [%]
Beam.Prop.rho = 69000;  % Mass per unit length [kg/m]

% Boundary conditions
supportType = app.BoundaryConditionsButtonGroup.SelectedObject;
if supportType == app.FixedButton
    Beam.BC.text = 'FF';
else
    Beam.BC.text = 'SP';
end

% % Boundary conditions - Alternative definitions
% Beam.BC.loc = [0,Beam.Prop.L/2,Beam.Prop.L];  % Location of supports
% Beam.BC.vert_stiff = [-1,-1,-1];              % Vertical stiffness of supports (-1 for perfectly stiff)
% Beam.BC.rot_stiff = [0,0,0];                  % Rotational stiffness of supports (-1 for perfectly stiff)
% Beam.BC.text = 'User-defined';
% Beam.BC.text_long = '2-span bridge';

% ---- End of script ---


% ---- Options ----
% Definition of addition relevant aspects of the model and calculation options

% Not a function.
% Defines the content of the variable Calc, 
%   and modifies the content of variables Beam and Track.

% ------------------------- Track irregularity ----------------------------

% ---- Smooth ---- (Default)
Calc.Profile.Type = 0;

% ---- FRA ---- 
% Reference: Ladislav Fryba. Dynamics of Railway Bridges. Academia Praha, 1996
Calc.Profile.Type = 1;      % Defined from PSD [m^3]
Calc.Profile.PSD_Y_fun = @(spaf,inputs) ...
    inputs(1)*inputs(3)^2*(spaf.^(2)+inputs(2)^2)./(spaf.^(4).*(spaf.^(2)+inputs(3)^2));
%Calc.Profile.inputs(1) = 15.53*1e-8;    % A [m^3] Class 1
%Calc.Profile.inputs(1) = 8.85*1e-8;     % A [m^3] Class 2
%Calc.Profile.inputs(1) = 4.92*1e-8;     % A [m^3] Class 3
%Calc.Profile.inputs(1) = 2.75*1e-8;     % A [m^3] Class 4
%Calc.Profile.inputs(1) = 1.57*1e-8;     % A [m^3] Class 5
Calc.Profile.inputs(1) = 0.98*1e-8;     % A [m^3] Class 6
Calc.Profile.inputs(2) = 23.3*1e-3;     % Omega1 [1/m]
Calc.Profile.inputs(3) = 13.1*1e-2;     % Omega2 [1/m]
Calc.Profile.min_WaveLength = 1.524;    % [m]
Calc.Profile.max_WaveLength = 304.8;    % [m]
Calc.Profile.text = 'FRA';

% % ---- FRA version 2 ----
% % Reference: Abdur Rohim Boy Berawi. Improving railway track maintenance
% %     using power spectral density (PSD). Doctoral thesis.
% %     Link: https://sigarra.up.pt/feup/en/pub_geral.show_file?pi_doc_id=12113
% Calc.Profile.Type = 1;  % Defined from PSD [m^3]
% Calc.Profile.PSD_Y_fun = @(spaf,inputs) (inputs(1)*inputs(2)*inputs(3)^2)./((spaf.^2).*(spaf.^2+inputs(3)^2))*inputs(4);
% Calc.Profile.inputs(1) = 0.25;          % k = Some constant
% %Calc.Profile.inputs(2) = 1.2107;   % A_v [cm^2 rad/m] Class 1
% %Calc.Profile.inputs(2) = 1.0181;   % A_v [cm^2 rad/m] Class 2
% %Calc.Profile.inputs(2) = 0.6816;   % A_v [cm^2 rad/m] Class 3
% %Calc.Profile.inputs(2) = 0.5376;   % A_v [cm^2 rad/m] Class 4
% %Calc.Profile.inputs(2) = 0.2095;   % A_v [cm^2 rad/m] Class 5
% Calc.Profile.inputs(2) = 0.0339;   % A_v [cm^2 rad/m] Class 6
% Calc.Profile.inputs(3) = 0.8245;   % Omega_c [rad/m]
% Calc.Profile.inputs(4) = 1e-4/(2*pi);   % Unit conversion [cm^2 = 10^(-4) m^2] and [rad = 1/(2*pi) cycles]
% Calc.Profile.min_WaveLength = 1.524;    % [m]
% Calc.Profile.max_WaveLength = 304.8;    % [m]
% Calc.Profile.text = 'FRAv2';

% % ---- German spectra for high-speed ----
% % Reference: W.W. Guo, H. Xia, G. De Roeck, K. Liu, Integral model for 
% %     train-track-bridge interaction on the Sesia viaduct: Dynamic simulation 
% %     and critical assessment, Computers & Structures, Volumes 112–113,
% %     2012, Pages 205-216, ISSN 0045-7949,
% %     https://doi.org/10.1016/j.compstruc.2012.09.001.
% Calc.Profile.Type = 1;  % Defined from PSD [m^3]
% Calc.Profile.PSD_Y_fun = @(spaf,inputs) (inputs(1)*inputs(2)^2)./((spaf.^2+inputs(3)^2).*(spaf.^2+inputs(2)^2))*inputs(4);
% Calc.Profile.inputs(1) = 4.032e-7*1/(2*pi)*(0.4/0.9843)^2;  % A_v [m^2 rad/m]
% Calc.Profile.inputs(2) = 0.8246*1/(2*pi);                   % Omega_c [rad/m]
% Calc.Profile.inputs(3) = 0.0206*1/(2*pi);                   % Omega_T [rad/m]
% Calc.Profile.inputs(4) = 1;                         % Unit conversion [rad = 1/(2*pi) cycles]
% Calc.Profile.min_WaveLength = 3;                    % [m]
% Calc.Profile.max_WaveLength = 150;                  % [m]
% Calc.Profile.text = 'German high-speed';

% % ---- SNCF ----
% % Reference: Abdur Rohim Boy Berawi. Improving railway track maintenance
% %     using power spectral density (PSD). Doctoral thesis.
% %     Link: https://sigarra.up.pt/feup/en/pub_geral.show_file?pi_doc_id=12113
% Calc.Profile.Type = 1;  % Defined from PSD [m^3]
% Calc.Profile.PSD_Y_fun = @(spaf,inputs) inputs(1)./(1+spaf/inputs(2)).^3;
% Calc.Profile.inputs(1) = 0.509*308*10^-6;       % A for good track
% %Calc.Profile.inputs(1) = 1.790*308*10^-6;      % A for poor track
% Calc.Profile.inputs(2) = 0.0489;                % n0 [1/m]
% Calc.Profile.min_WaveLength = 2;                % [m]
% Calc.Profile.max_WaveLength = 40;               % [m]
% Calc.Profile.text = 'SNCF';

% % ---- Load existing profile ----
% Calc.Profile.Type = 5;
% Calc.Profile.Load.file_name = '20171207_FRA_Class6_500m';
% Calc.Profile.Load.path_name = 'Results\Profiles\';

% --------------------------- Mesh Properties -----------------------------

% -- Element size for Bridge model --
Beam.Mesh.Ele.num_per_spacing = 2;

% -- Element size for rail in Track model --
Track.Rail.Mesh.Ele.num_per_spacing = 2;

% -- Approach distance --
Calc.Profile.minL_Approach = 30;    % Approach distance [m]

% -- Distance after crossing -- (To allow for free vibration)
Calc.Profile.minL_After = 30;   % [m] (Default value = 10)

% ----------------- Calculation options and variables ---------------------

% Results at particular bridge sections
Calc.Options.calc_beam_sections = [Beam.Prop.L/2];      % (Default = [])

% Time step
Calc.Solver.max_accurate_frq = 500;     % [Hz]

% Consider VBI
Calc.Options.VBI = 1;        % VBI is considered (default)
%Calc.Options.VBI = 0;        % No VBI considered = Moving force solution 

% Redux model option
Calc.Options.redux = 0;         % Original script. Track under the vehicle
%Calc.Options.redux = 1;         % No track under vehicle at start and end of simulation (Default)

% Other
Calc.Options.calc_beam_modes = 0;   % In modal analysis, no modes are calculated

% -------------------------------------------------------------------------
% ------------------------- Plotting Options ------------------------------

% Note: Comment/uncomment to switch off/on the corresponding plot

% ---- Vehicle ----
%Calc.Plot.Veh.P01_VertDisp = 1;         % Vehicle vertical diplacements
%Calc.Plot.Veh.P02_VertVel = 1;          % Vehicle vertical velocities
Calc.Plot.Veh.P03_VertAcc = 1;          % Vehicle vertical accelerations
Calc.Plot.Veh.P04_ContactForce_t = 1;   % Vehicle vertical contact force in time (1 = Values; 2 = With 0 limit)
%Calc.Plot.Veh.P05_ContactForce_x = 1;   % Vehicle vertical contact force in space (1 = Values; 2 = With 0 limit)

% ---- Model ----
Calc.Plot.Model.P00_ModelVisualization = 1;% Sketch of model before performing simulation
%Calc.Plot.Model.P01_ModelDef = 50;       % Model vertical deformation at X% of the total simulated time
%Calc.Plot.Model.P02_ModelRot = 10;       % Model rotation values at X% of the total simulated time

% ---- Beam ----
%Calc.Plot.Beam.P01_DispContour = 1;          % Beam displacement contour plot
Calc.Plot.Beam.P02_StaticDispContour = 1;    % Beam static displacment contour plot
Calc.Plot.Beam.P03_BMContour = 1;            % Beam Bending moment contour plot
%Calc.Plot.Beam.P04_StaticBMContour = 1;      % Beam Static Bending moment contour plot
%Calc.Plot.Beam.P05_ShearContour = 1;         % Beam Shear contour plot
%Calc.Plot.Beam.P06_StaticShearContour = 1;   % Beam Static Shear contour plot
%Calc.Plot.Beam.P07_VertAccContour = 1;       % Beam Vertial Acceleration contour plot
%Calc.Plot.Beam.P08_Sections_BeamVertDisp = 1;% Beam vertical displacement at selected sections
%Calc.Plot.Beam.P09_Sections_BeamBM = 1;      % Beam Bending Moment at selected sections
%Calc.Plot.Beam.P10_Sections_BeamShear = 1;   % Beam Shear at selected sections
Calc.Plot.Beam.P11_Sections_BeamAcc = 1;     % Beam Acceleration at selected sections

% ---- Others ----
%Calc.Plot.Profile_original = 1;         % Generated Profile
%Calc.Plot.P3_VehPos = 1;                % Vehicle position in time

% ---- End of script ----


% --------------------------- Calculations --------------------------------


% -- Model geometry --
[Calc,Train,Beam] = B43_ModelGeometry(Calc,Train,Track,Beam);

% -- Options Processing --
[Calc,Train,Track,Beam] = B07_OptionsProcessing(Calc,Train,Track,Beam);

% ---- Beam Model ----
% Elements and coordinates
[Beam] = B01_ElementsAndCoordinates(Beam,Calc);
% Boundary conditions 
[Beam] = B02_BoundaryConditions(Beam);
% Beam system matrices (Mass and Stiffness)
[Beam] = B03_BeamMatrices(Beam);
% Beam frequencies 
[Beam] = B09_BeamFrq(Beam,Calc);
% Beam Damping Matrix
[Beam] = B24_BeamDamping(Beam);

% ---- Track Model ----
% Definition of auxiliary variables
[Track] = B51_RailVariables(Track,Calc);
% Elements and coordinates
[Track.Rail] = B01_ElementsAndCoordinates(Track.Rail);
% Boundary conditions 
[Track.Rail] = B02_BoundaryConditions(Track.Rail);
% Rail system matrices (Mass and stiffness)
[Track.Rail] = B03_BeamMatrices(Track.Rail);
% Rail frequencies
[Track.Rail] = B09_BeamFrq(Track.Rail,Calc);
% Reference frequencies for damping definition
Track.Rail.Modal.w = [zeros(Track.Rail.Modal.num_rigid_modes,1),Beam.Modal.w(1:2)];
% Beam Damping Matrix
[Track.Rail] = B24_BeamDamping(Track.Rail);

% ---- Complete Model ----
% System matrices

% Appending the Text Area
currentText = app.ConsoleTextArea.Value;
newText = 'Building model system matrices ...';
updatedText = [currentText ; newText];
app.ConsoleTextArea.Value = updatedText;
drawnow;       
app.ConsoleTextArea.scroll('bottom');

[Model] = B54_ModelMatrices(Beam,Track,Calc);
% Model boundary conditions
[Model] = B55_ModelBC(Model,Beam,Track);
% Modal analysis of Model
[Model] = B56_ModelFrq(Model,Calc);
B57_PlotModelModes(Model,Calc);


currentText = app.ConsoleTextArea.Value;
newText = 'DONE';
updatedText = [currentText ; newText];
app.ConsoleTextArea.Value = updatedText;
drawnow;  
app.ConsoleTextArea.scroll('bottom');

% ---- Vehicle Model ----
% Vehicles system matrices
[Train.Veh] = B18_TrainVehEq(Train.Veh);
% Vehicle frequencies
[Train.Veh] = B08_VehFreq(Train.Veh,Calc);
% Vehicle position and Solver time array
[Calc] = B10_EndTime(Calc);
[Calc] = B11_TimeSpaceDiscretization(Calc);
% Vehicle static loads
[Train.Veh] = B47_VehStaticLoads(Train.Veh,Calc);

% ---- Irregularity profile ----
% Generation
[Calc] = B19_GenerateProfile(Calc);
% Assigning profile to each wheel
[Calc] = B25_WheelProfiles(Calc,Train.Veh);

% -- Model visualization --
% B67_PlotModelVisualization(app.UIAxes3,app.ConsoleTextArea,Calc,Train.Veh,Track,Model);
% drawnow;  
% app.ConsoleTextArea.scroll('bottom');
    
% ---- Coupled Equations Solver (COUP) ----
% Element number of vertical forces in time
[Calc] = B50_ElementNumOfForce(Track.Rail,Calc);
% Initial coupled system's static deformation
[Sol] = B64_Coupled_InitialStatic(Train.Veh,Model,Calc,Track);
tic; 


currentText = app.ConsoleTextArea.Value;
newText = 'Performing Dynamic Calculations (Coupled System) ...';
updatedText = [currentText ; newText];
app.ConsoleTextArea.Value = updatedText;
drawnow;  
app.ConsoleTextArea.scroll('bottom');

[Sol] = B65_DynamicCalcCoupledFaster(app.ConsoleTextArea,Train.Veh,Model,Calc,Track,Sol);
    drawnow;  
    app.ConsoleTextArea.scroll('bottom');
    
currentText = app.ConsoleTextArea.Value;
newText = ['Calculation time: ',num2str(round(toc,2)),'s'];
updatedText = [currentText ; newText];
app.ConsoleTextArea.Value = updatedText;
drawnow;  
app.ConsoleTextArea.scroll('bottom');

% ---- Additional results from solution ----
% Contact Force
[Sol] = B66_ContactForce(Sol,Track,Calc,Train);
% Beam deformation
[Sol] = B49_BeamDeformation(Sol,Model,Beam,Calc,Train,1);
% Static beam deformation
[Sol] = B49_BeamDeformation(Sol,Model,Beam,Calc,Train,0);
% Beam Bending Moment
[Sol] = B31_BeamBM(Sol,Model,Beam,Calc,1);
% Static Beam Bending Moment
[Sol] = B31_BeamBM(Sol,Model,Beam,Calc,0);
% Beam Shear Force
[Sol] = B33_BeamShear(Sol,Model,Beam,Calc,1);
% Static Beam Shear Force
[Sol] = B33_BeamShear(Sol,Model,Beam,Calc,0);
% Beam Acceleration
[Sol] = B53_BeamAcceleration(Sol,Model,Beam,Calc);
% Results at selected Beam sections
[Sol] = B58_ResultsBeamSections(Sol,Beam,Calc);


currentText = app.ConsoleTextArea.Value;
newText = 'All calculations finished sucessfully';
updatedText = [currentText ; newText];
app.ConsoleTextArea.Value = updatedText;
drawnow; 
app.ConsoleTextArea.scroll('bottom');

% ---- End of script ----

%% -------------------- Results processing and plotting --------------------
%%------------App Designer-------------------
sp_or = Train.vel;
% Display the data
% numeric_data
t_per = 50;
% Selection of time-step to plot
Data = Sol.Model.Nodal.A;
t = round(Calc.Solver.num_t*t_per/100)+1-ceil(t_per/100);
x = linspace(1,round(500/sp_or),length( Model.Mesh.XLoc.rail_vert));
y = Data(Model.Mesh.DOF.rail_vert,t);
plot(app.UIAxes,x,y)


app.GlobalTime = x;
app.GlobalAcceleration=y;

Data = Sol.Model.Nodal.V;
t = round(Calc.Solver.num_t*t_per/100)+1-ceil(t_per/100);
x = linspace(1,round(500/sp_or),length( Model.Mesh.XLoc.rail_vert));
y = Data(Model.Mesh.DOF.rail_vert,t);
plot(app.UIAxes2,x,y)

app.GlobalSpeed = y;

app.DangerZonePanel.Enable = 'on';
app.ExportDataPanel.Enable = 'on';
app.ConfigurationTrainTrackandBridgePanel.Enable = 'on';
app.RunButton.Enable = 'on';
%%---------------------------Matlab
% % Model deformation plot at a given instant in time
% C01_ModelDeformationPlot(Calc,Model,Sol,Train);
% 
% % % % Time history plot of user selected model point
% C02_TimeHistoryPlot(Model,Sol,Beam,Calc);
% % 
% % % Other user-specified plots of the results
% C03_TTB_2D_Plots;

% ---- End of script ----
        end

        % Value changed function: VelocitykmphEditField
        function VelocitykmphEditFieldValueChanged(app, event)

        end

        % Button pushed function: AccelerationButton
        function AccelerationButtonPushed(app, event)
            % The file name
            % Get the current system date and time
            currentDateTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
            
            % Convert to string without spaces or delimiters
            dateString = datestr(currentDateTime, 'yyyymmddHHMMSS');
            filename = 'Acceleration_'+string(dateString)+'.xlsx';
            writematrix([(app.GlobalTime)',app.GlobalAcceleration], filename, 'Sheet', 'AccelerationData');
        end

        % Button pushed function: VelocityButton
        function VelocityButtonPushed(app, event)
            % The file name
            % Get the current system date and time
            currentDateTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
            
            % Convert to string without spaces or delimiters
            dateString = datestr(currentDateTime, 'yyyymmddHHMMSS');
            filename = 'Velocity_'+string(dateString)+'.xlsx';
            
            % Write arrays to Excel file
            writematrix([(app.GlobalTime)';(app.GlobalSpeed)], filename, 'Sheet', 'VelocityData');
        end

        % Button pushed function: VelocityandAccelerationButton
        function VelocityandAccelerationButtonPushed(app, event)
                        % The file name
            % Get the current system date and time
            currentDateTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
            
            % Convert to string without spaces or delimiters
            dateString = datestr(currentDateTime, 'yyyymmddHHMMSS');
            filename = 'AccelerationAndVelocity_'+string(dateString)+'.xlsx';
            writematrix([(app.GlobalTime)',app.GlobalAcceleration], filename, 'Sheet', 'AccelerationData');
            writematrix([(app.GlobalTime)',app.GlobalSpeed], filename, 'Sheet', 'VelocityData');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.DangerZonePanel.Enable = 'off';
            app.ExportDataPanel.Enable = 'off';
            app.ConfigurationTrainTrackandBridgePanel.Enable = 'on';
            app.RunButton.Enable = 'on';
            cla(app.UIAxes);
            cla(app.UIAxes2);
            app.ConsoleTextArea.Value = '';
            drawnow;  
        end

        % Button pushed function: AccelerationButton_2
        function AccelerationButton_2Pushed(app, event)
            % Get the current system date and time
            currentDateTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
            % Convert to string without spaces or delimiters
            dateString = datestr(currentDateTime, 'yyyymmddHHMMSS');
            filename = 'Acceleration_'+string(dateString)+'.mat';
            acc =app.GlobalAcceleration;
            t = (app.GlobalTime)';
            save(filename,'t','acc');
        end

        % Button pushed function: VelocityButton_2
        function VelocityButton_2Pushed(app, event)
            % Get the current system date and time
            currentDateTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
            % Convert to string without spaces or delimiters
            dateString = datestr(currentDateTime, 'yyyymmddHHMMSS');
            filename = 'Velocity_'+string(dateString)+'.mat';
            sp = app.GlobalSpeed;
            t = (app.GlobalTime)';
            save(filename,'t','sp');
        end

        % Button pushed function: VelocityandAccelerationButton_2
        function VelocityandAccelerationButton_2Pushed(app, event)
            % Get the current system date and time
            currentDateTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
            % Convert to string without spaces or delimiters
            dateString = datestr(currentDateTime, 'yyyymmddHHMMSS');
            filename = 'AccelerationAndVelocity_'+string(dateString)+'.mat';
            acc =app.GlobalAcceleration;
            sp = app.GlobalSpeed;
            t = (app.GlobalTime)';
            save(filename,'t','acc','sp');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Run the App to get Results ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Run the App to get Results ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ConsoleTextAreaLabel = uilabel(app.UIFigure);
            app.ConsoleTextAreaLabel.Text = 'Console...';
            app.ConsoleTextArea = uitextarea(app.UIFigure);
            app.ConsoleTextArea.Editable = 'off';
            app.ConfigurationTrainTrackandBridgePanel = uipanel(app.UIFigure);
            app.ConfigurationTrainTrackandBridgePanel.Title = 'Configuration (Train, Track and Bridge)';
            app.CoachNum = uispinner(app.ConfigurationTrainTrackandBridgePanel);
            app.CoachNum.Limits = [1 20];
            app.CoachNum.Value = 5;
            app.NumberofCoachesExcludingLocomotivesSpinnerLabel = uilabel(app.ConfigurationTrainTrackandBridgePanel);
            app.NumberofCoachesExcludingLocomotivesSpinnerLabel.Text = 'Number of Coaches (Excluding Locomotives)';
            app.VelocitykmphEditField = uieditfield(app.ConfigurationTrainTrackandBridgePanel, 'numeric');
            app.VelocitykmphEditField.Limits = [40 200];
            app.VelocitykmphEditField.RoundFractionalValues = 'on';
            app.VelocitykmphEditField.ValueChangedFcn = createCallbackFcn(app, @VelocitykmphEditFieldValueChanged, true);
            app.VelocitykmphEditField.Value = 100;
            app.VelocitykmphEditFieldLabel = uilabel(app.ConfigurationTrainTrackandBridgePanel);
            app.VelocitykmphEditFieldLabel.Text = 'Velocity (kmph)';
            app.Label = uilabel(app.ConfigurationTrainTrackandBridgePanel);
            app.Label.Text = 'Note: Large Number of Coaches will take longer time for the program to execute.';
            app.ModelTrainPropertiesButtonGroup = uibuttongroup(app.ConfigurationTrainTrackandBridgePanel);
            app.ModelTrainPropertiesButtonGroup.Title = 'Model (Train Properties)';
            app.Manchester = uiradiobutton(app.ModelTrainPropertiesButtonGroup);
            app.Manchester.Text = 'Manchester Benchmark';
            app.Manchester.Value = true;
            app.AVE = uiradiobutton(app.ModelTrainPropertiesButtonGroup);
            app.AVE.Text = 'AVE S103 ICE3 Velaro E';
            app.Eurostar = uiradiobutton(app.ModelTrainPropertiesButtonGroup);
            app.Eurostar.Text = 'Eurostar';
            app.Chinese = uiradiobutton(app.ModelTrainPropertiesButtonGroup);
            app.Chinese.Text = 'Chinese Star';
            app.PoineerM = uiradiobutton(app.ModelTrainPropertiesButtonGroup);
            app.PoineerM.Text = 'Poineer M';
            app.PoineerR = uiradiobutton(app.ModelTrainPropertiesButtonGroup);
            app.PoineerR.Text = 'Poineer R';
            app.Shinkansen = uiradiobutton(app.ModelTrainPropertiesButtonGroup);
            app.Shinkansen.Text = 'Shinkansen S300';
            app.BallastButtonGroup = uibuttongroup(app.ConfigurationTrainTrackandBridgePanel);
            app.BallastButtonGroup.Title = 'Ballast';
            app.WithBallastonTrackButton = uiradiobutton(app.BallastButtonGroup);
            app.WithBallastonTrackButton.Text = 'With Ballast on Track';
            app.WithBallastonTrackButton.Value = true;
            app.NoBallastonTrackButton = uiradiobutton(app.BallastButtonGroup);
            app.NoBallastonTrackButton.Text = 'No Ballast on Track';
            app.BoundaryConditionsButtonGroup = uibuttongroup(app.ConfigurationTrainTrackandBridgePanel);
            app.BoundaryConditionsButtonGroup.Title = 'Boundary Conditions';
            app.SimplySupportedButton = uiradiobutton(app.BoundaryConditionsButtonGroup);
            app.SimplySupportedButton.Text = 'Simply Supported';
            app.SimplySupportedButton.Value = true;
            app.FixedButton = uiradiobutton(app.BoundaryConditionsButtonGroup);
            app.FixedButton.Text = 'Fixed';
            app.ExportDataPanel = uipanel(app.UIFigure);
            app.ExportDataPanel.Enable = 'off';
            app.ExportDataPanel.Title = 'Export Data';
            app.GridLayout = uigridlayout(app.ExportDataPanel);
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'Note: Single Click is enough for Exporting the Data';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = 'Excel:';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = 'Mat File:';
            app.VelocityButton = uibutton(app.GridLayout, 'push');
            app.VelocityButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityButtonPushed, true);
            app.VelocityButton.Text = 'Velocity';
            app.AccelerationButton = uibutton(app.GridLayout, 'push');
            app.AccelerationButton.ButtonPushedFcn = createCallbackFcn(app, @AccelerationButtonPushed, true);
            app.AccelerationButton.Text = 'Acceleration';
            app.VelocityandAccelerationButton = uibutton(app.GridLayout, 'push');
            app.VelocityandAccelerationButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityandAccelerationButtonPushed, true);
            app.VelocityandAccelerationButton.Text = 'Velocity and Acceleration';
            app.AccelerationButton_2 = uibutton(app.GridLayout, 'push');
            app.AccelerationButton_2.ButtonPushedFcn = createCallbackFcn(app, @AccelerationButton_2Pushed, true);
            app.AccelerationButton_2.Text = 'Acceleration';
            app.VelocityButton_2 = uibutton(app.GridLayout, 'push');
            app.VelocityButton_2.ButtonPushedFcn = createCallbackFcn(app, @VelocityButton_2Pushed, true);
            app.VelocityButton_2.Text = 'Velocity';
            app.VelocityandAccelerationButton_2 = uibutton(app.GridLayout, 'push');
            app.VelocityandAccelerationButton_2.ButtonPushedFcn = createCallbackFcn(app, @VelocityandAccelerationButton_2Pushed, true);
            app.VelocityandAccelerationButton_2.Text = 'Velocity and Acceleration';
            app.DangerZonePanel = uipanel(app.UIFigure);
            app.DangerZonePanel.Enable = 'off';
            app.DangerZonePanel.Title = 'Danger Zone';
            app.ResetButton = uibutton(app.DangerZonePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label_5 = uilabel(app.DangerZonePanel);
            app.Label_5.Text = 'All the outputs would be erased.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end